classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ThermocoupleSimulatorUIFigure  matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        SimulateButton                 matlab.ui.control.Button
        StartingTemperatureEditFieldLabel  matlab.ui.control.Label
        StartingTemperatureEditField   matlab.ui.control.NumericEditField
        ThermocoupleTypeDropDownLabel  matlab.ui.control.Label
        ThermocoupleTypeDropDown       matlab.ui.control.DropDown
        EndingTemperatureEditFieldLabel  matlab.ui.control.Label
        EndingTemperatureEditField     matlab.ui.control.NumericEditField
        ClearScreenChkBox              matlab.ui.control.CheckBox
        BestThermChkBox                matlab.ui.control.CheckBox
        ClearAxesButton                matlab.ui.control.Button
        TemperatureStepEditFieldLabel  matlab.ui.control.Label
        TemperatureStepEditField       matlab.ui.control.NumericEditField
        PlotInFigureChkBox             matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        temperature = [-1 1];               %Holds temperature range to be used in simulation
        previous_voltage_response = [0 0];  %Holds previous thermocouple voltage response
        thermocouple_type = 1;              %Type of thermocouple, default is type J
        thermocouple_volt = 0;
        %ITS-90 standards fetched from NIST site
        coef_k=[0 3.9450128025e1 2.3622373598e-2 -3.2858906784e-4 -4.9904828777e-6 -6.7509059173e-8 -5.7410327428e-10 -3.1088872894e-12 -1.0451609365e-14 -1.9889266878e-17 -1.6322697486e-20 -1.7600413686e1 3.8921204975e1 1.8558770032e-2 -9.9457592874e-5 3.1840945719e-7 -5.6072844889e-10 5.6075059059e-13  -3.2020720003e-16 9.7151147152e-20  -1.2104721275e-23];
        a0=1.185976e2;
        a1=-1.183432e-4;
        %Coefficients for type J [-210 1200]
        coef_j=[0 5.0381187815e1 3.0475836930e-2 -8.5681065720e-5 1.3228195295e-7 -1.7052958337e-10 2.0948090697e-13 -1.2538395336e-16 1.5631725697e-20 2.9645625681e5 -1.4976127786e3 3.1787103924 -3.1847686701e-3 1.5720819004e-6 -3.0691369056e-10];
        %Coefficients for type T [-270 400]
        coef_t=[0 38.7481063640000 0.0441944343470000 0.000118443231050000 2.00329735540000e-05 9.01380195590000e-07 2.26511565930000e-08 3.60711542050000e-10 3.84939398830000e-12 2.82135219250000e-14 1.42515947790000e-16 4.87686622860000e-19 1.07955392700000e-21 1.39450270620000e-24 7.97951539270000e-28 0 38.7481063640000 0.0332922278800000 0.000206182434040000 -2.18822568460000e-06 1.09968809280000e-08 -3.08157587720000e-11 4.54791352900000e-14 -2.75129016730000e-17];
        %Coefficients for type E [-270 1000]
        coef_e=[0 58.6655087080000 0.0454109771240000 -0.000779980486860000 -2.58001608430000e-05 -5.94525830570000e-07 -9.32140586670000e-09 -1.02876055340000e-10 -8.03701236210000e-13 -4.39794973910000e-15 -1.64147763550000e-17 -3.96736195160000e-20 -5.58273287210000e-23 -3.46578420130000e-26 0 58.6655087100000 0.0450322755820000 2.89084072120000e-05 -3.30568966520000e-07 6.50244032700000e-10 -1.91974955040000e-13 -1.25366004970000e-15 2.14892175690000e-18 -1.43880417820000e-21 3.59608994810000e-25];
        %Coefficients for type N
        coef_n=[0 26.1591059620000 0.0109574842280000 -9.38411115540000e-05 -4.64120397590000e-08 -2.63033577160000e-09 -2.26534380030000e-11 -7.60893007910000e-14 -9.34196678350000e-17 0 25.9293946010000 0.0157101418800000 4.38256272370000e-05 -2.52611697940000e-07 6.43118193390000e-10 -1.00634715190000e-12 9.97453389920000e-16 -6.08632456070000e-19 2.08492293390000e-22 -3.06821961510000e-26];
        %Coefficients for type B
        coef_b=[0 -2.4650818346e-1 5.9040421171e-3 -1.3257931636e-6 1.5668291901e-9 -1.6944529240e-12 6.2290347094e-16 -3.8938168621e3 2.8571747470e1 -8.4885104785e-2 1.5785280164e-4 -1.6835344864e-7 1.1109794013e-10 -4.4515431033e-14 9.8975640821e-18 -9.3791330289e-22];
        %Coefficients for type R
        coef_r=[0 5.28961729765000 0.0139166589782000 -2.38855693017000e-05 3.56916001063000e-08 -4.62347666298000e-11 5.00774410340000e-14 -3.73105886191000e-17 1.57716482367000e-20 -2.81038625251000e-24 2951.57925316000 -2.52061251332000 0.0159564501865000 -7.64085947576000e-06 2.05305291024000e-09 -2.93359668173000e-13 152232.118209000 -268.819888545000 1.17128028047100 -3.45895706453000e-05 -9.34633971046000e-12];
        %Coefficients for type S
        coef_s=[0 5.40313308631000 0.0125934289740000 -2.32477968689000e-05 3.22028823036000e-08 -3.31465163890000e-11 2.55744251786000e-14 -1.25068871393000e-17 2.71443176145000e-21 1329.00445085000 3.34509311344000 0.00654805192818000 -1.64856259209000e-06 1.29989605174000e-11 146628.232636000 -258.430516752000 0.163693574641000 -3.30439046987000e-05 -9.43223690612000e-12];
    end
    
    
    methods (Access = public)
        
        function result = validate_input(app) %Validates that thermocouple can work in given temperature range
            temp = app.temperature;
            switch (app.ThermocoupleTypeDropDown.Value)
                case '1' %Type J
                    if (or((min(temp) < -210),(max(temp) > 1200))) %Type J range [-210, 1200] C
                        result = 0;
                    else
                        result = 1; %Input range is valid
                    end
                case '2' %Type K
                    if (or((min(temp) < -270),(max(temp) > 1372))) %Type K range [-270, 1372] C
                        result= 0; % Input range is invalid
                    else
                        result = 1; %Input range is valid
                    end
                case '3' %Type E
                    if (or((min(temp) < -270),(max(temp) > 1000))) %Type E range [-270, 1000] C
                        result= 0; % Input range is invalid
                    else
                        result = 1; %Input range is valid
                    end
                case '4' %Type T
                    if (or((min(temp) < -270),(max(temp) > 400))) %Type T range [-270,400]
                        result= 0; % Input range is invalid
                    else
                        result = 1; %Input range is valid
                    end
                case '5' %Type N
                    if (or((min(temp) < -270),(max(temp) > 1300))) %Type N range [-270,1300]
                        result= 0; % Input range is invalid
                    else
                        result = 1; %Input range is valid
                    end
                case '6' %Type B
                    if (or((min(temp) < 0),(max(temp) > 1820))) %Type B range [0,1820]
                        result= 0; % Input range is invalid
                    else
                        result = 1; %Input range is valid
                    end
                case '7' %Type R
                    if (or((min(temp) < -50),(max(temp) > 1768.1))) %Type r range [-50,1768]
                        result= 0; % Input range is invalid
                    else
                        result = 1; %Input range is valid
                    end
                case '8' %Type S
                    if (or((min(temp) < -50),(max(temp) > 1768.1))) %Type r range [-50,1768]
                        result= 0; % Input range is invalid
                    else
                        result = 1; %Input range is valid
                    end
                otherwise
                    errordlg("No data entry for selected thermopile","Invalid thermocouple"); %Should be impossible, but just in case of bug
                    result = 0;
            end
        end
        
        function result = thermocouple_voltage(app) %Calculates thermocouple voltage and turns it in mV
            temp = app.temperature;
            results(length(temp))=0;                %Dummy result holder
            switch (app.ThermocoupleTypeDropDown.Value)
                case '1' %Type J
                    for j=1:length(temp)
                        if (temp(j)>=-210 && temp(j)<760)       %For temps [-210 760)
                            for i=1:9
                                results(j)=app.coef_j(i)*(temp(j)^(i-1)) + results(j);
                            end
                        elseif (temp(j)>=760 && temp(j)<=1200)   %For temps [760 1200]
                            for i=10:15
                                results(j)=app.coef_j(i)*(temp(j)^(i-10)) + results(j);
                            end
                        end
                    end
                case '2' %Type K
                    for j=1:length(temp)
                        if (temp(j)<=0 && temp(j)>= -270)        %For temps [-270 0)
                            for i=1:11
                                results(j)=app.coef_k(i)*(temp(j)^(i-1)) + results(j);
                            end
                        elseif (temp(j)>0 && temp(j)<=1372)    %For temps [0 1372]
                            for i=12:21
                                results(j)=(app.coef_k(i)*(temp(j)^(i-12)) + (app.a0*exp(app.a1*((temp(j)-126.9686)^2))) + results(j));
                            end
                        end
                    end
                case '3' %Type E
                    for j=1:length(temp)
                        if (temp(j)>=-270 && temp(j)<0)         %For temps [-270 0)
                            for i=1:14
                                results(j)=app.coef_e(i)*(temp(j)^(i-1)) + results(j);
                            end
                        elseif (temp(j)>=0 && temp(j)<=1000)    %For temps [0 1000]
                            for i=14:25
                                results(j)=app.coef_e(i)*(temp(j)^(i-14)) + results(j);
                            end
                        end
                    end
                case '4' %Type T
                    for j=1:length(temp)
                        if (temp(j)>=-270 && temp(j)<0)         %For temps [-270 0)
                            for i=1:15
                                results(j)=app.coef_t(i)*(temp(j)^(i-1)) + results(j);
                            end
                        elseif (temp(j)>=0 && temp(j)<=400)     %For temps [0 400]
                            for i=15:24
                                results(j)=app.coef_t(i)*(temp(j)^(i-15)) + results(j);
                            end
                        else
                        end
                    end
                case '5' %Type N
                    for j=1:length(temp)
                        if (temp(j)>=-270 && temp(j)<0)         %For temps [-270 0)
                            for i=1:9
                                results(j)=app.coef_n(i)*(temp(j)^(i-1)) + results(j);
                            end
                        elseif (temp(j)>=0 && temp(j)<=1300)     %For temps [0 1300]
                            for i=9:20
                                results(j)=app.coef_n(i)*(temp(j)^(i-9)) + results(j);
                            end
                        else
                        end
                    end
                case '6' %Type B
                    for j=1:length(temp)
                        if (temp(j)>=0 && temp(j)<630.615)         %For temps [0 630.615)
                            for i=1:7
                                results(j)=app.coef_b(i)*(temp(j)^(i-1)) + results(j);
                            end
                        elseif (temp(j)>=630.615 && temp(j)<=1820)     %For temps [630.615 1820]
                            for i=8:16
                                results(j)=app.coef_b(i)*(temp(j)^(i-8)) + results(j);
                            end
                        else
                        end
                    end
                case '7' %Type R
                    for j=1:length(temp)
                        if (temp(j)>=-50 && temp(j)<1064.18)         %For temps [-50 1064.18)
                            for i=1:10
                                results(j)=app.coef_r(i)*(temp(j)^(i-1)) + results(j);
                            end
                        elseif (temp(j)>=1064.18 && temp(j)<1664.5) %For temps [0 1664.5)
                            for i=11:16
                                results(j)=app.coef_r(i)*(temp(j)^(i-11)) + results(j);
                            end
                        elseif (temp(j)>=1664.5 && temp(j)<=1768.1)
                            for i=17:21
                                results(j)=(app.coef_r(i)*(temp(j)^(i-17)) + results(j));
                            end
                        else
                        end
                    end
                case '8' %Type S
                    for j=1:length(temp)
                        if (temp(j)>=-50 && temp(j)<1064.18)         %For temps [-50 250)
                            for i=1:9
                                results(j)=app.coef_s(i)*(temp(j)^(i-1)) + results(j);
                            end
                        elseif (temp(j)>=1064.18 && temp(j)<1664.5)
                            for i=10:14
                                results(j)=(app.coef_s(i)*(temp(j)^(i-10)) + results(j));
                            end
                        elseif (temp(j)>=1664.5 && temp(j)<1768.1)     %For temps [250 1200)
                            for i=15:19
                                results(j)=(app.coef_s(i)*(temp(j)^(i-15)) + results(j));
                            end
                        else
                        end
                    end
            end
            if (app.ThermocoupleTypeDropDown.Value == '3' || app.ThermocoupleTypeDropDown.Value == '4' || app.ThermocoupleTypeDropDown.Value == '5')
                result = results * 0.000001;
            else
                result = results*0.001; %Polynomial results are uV so we multiply by 0.001 to convert them in mV
            end
        end
        
        function results = data_plot(app)
            func_handler = @validate_input;
            check_result = func_handler(app);
            switch (app.BestThermChkBox.Value)
                case 0                                          %User wants to test a specific thermocouple type
                    if (check_result == 1)                      %The temperature range is valid for selected thermocouple
                        func_handler = @thermocouple_voltage;
                        check_result = func_handler(app);
                        if (app.ClearScreenChkBox.Value == 1)   %The Don't clear screen is checked
                            if (app.PlotInFigureChkBox.Value == 1)
                                figure();
                                hold ('on');
                                plot(app.temperature,check_result); xlabel('Temperature (C)');ylabel('Voltage (mV)');title('Thermocouple Voltage Response');
                                legend(strcat((app.ThermocoupleTypeDropDown.Items{1,str2num(app.ThermocoupleTypeDropDown.Value)}),": Min:",num2str(min(check_result))," , Max:",num2str(max(check_result))));
                            else
                                hold (app.UIAxes, 'on');
                                xlabel(app.UIAxes,'Temperature (C)');ylabel(app.UIAxes,'Voltage (mV)');
                                plot(app.UIAxes,app.temperature,check_result);
                                legend(app.UIAxes,strcat((app.ThermocoupleTypeDropDown.Items{1,str2num(app.ThermocoupleTypeDropDown.Value)}),": Min:",num2str(min(check_result))," , Max:",num2str(max(check_result))));
                            end
                        else
                            if(app.PlotInFigureChkBox.Value == 1)
                                figure();
                                hold ('off');
                                plot(app.temperature,check_result);xlabel('Temperature (C)');ylabel('Voltage (mV)');title('Thermocouple Voltage Response');
                                legend(strcat((app.ThermocoupleTypeDropDown.Items{1,str2num(app.ThermocoupleTypeDropDown.Value)}),": Min:",num2str(min(check_result))," , Max:",num2str(max(check_result))));
                            else
                                hold (app.UIAxes,'off');
                                plot(app.UIAxes,app.temperature,check_result); xlabel(app.UIAxes,'Temperature (C)');ylabel(app.UIAxes,'Voltage (mV)');
                                legend(app.UIAxes,strcat((app.ThermocoupleTypeDropDown.Items{1,str2num(app.ThermocoupleTypeDropDown.Value)}),": Min:",num2str(min(check_result))," , Max:",num2str(max(check_result))));
                            end
                        end
                    else                                        %The temperature range is invalid
                        errordlg("Specified type is invalid in this range","Invalid range for specific thermocouple");
                    end
                case 1                                          %User wants to automatically determine best thermocouple type
                    cla(app.UIAxes,'reset');
                    for i=1:length(app.ThermocoupleTypeDropDown.ItemsData)
                        func_handler = @validate_input;
                        check_result = func_handler(app);
                        app.ThermocoupleTypeDropDown.Value = num2str(i);
                        if (check_result == 1)
                            app.thermocouple_volt = 0;
                            func_handler = @thermocouple_voltage;
                            check_result = func_handler(app);
                            if (max(app.previous_voltage_response) < max(check_result))
                                app.previous_voltage_response = check_result;
                                app.thermocouple_volt = check_result;
                                app.thermocouple_type = i;
                            end
                        else
                            continue;
                        end
                    end
                    if (app.PlotInFigureChkBox.Value ==1)
                        app.ThermocoupleTypeDropDown.Value = num2str(app.thermocouple_type);
                        figure();
                        plot(app.temperature,check_result);
                        xlabel('Temperature (C)');ylabel('Voltage (mV)');title('Thermocouple Voltage Response');
                        legend(strcat((app.ThermocoupleTypeDropDown.Items{1,str2num(app.ThermocoupleTypeDropDown.Value)}),": Min:",num2str(min(check_result))," , Max:",num2str(max(check_result))));
                        app.thermocouple_volt = 0;
                    else
                        app.ThermocoupleTypeDropDown.Value = num2str(app.thermocouple_type);
                        xlabel(app.UIAxes,'Temperature (C)');ylabel(app.UIAxes,'Voltage (mV)');
                        plot(app.UIAxes,app.temperature,check_result);
                        legend(app.UIAxes,strcat((app.ThermocoupleTypeDropDown.Items{1,str2num(app.ThermocoupleTypeDropDown.Value)}),": Min:",num2str(min(check_result))," , Max:",num2str(max(check_result))));
                        app.thermocouple_volt = 0;
                    end
            end
            results = 1;
        end
    end
    
    methods (Access = private)
        
        function result = error_handler(app)    %Function to display errors
            func_handler = @validate_input;
            check_result = func_handler(app);
            result = check_result;
        end
        
        
    end

    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            func_handler = @data_plot;
            result = func_handler(app);
        end

        % Value changed function: EndingTemperatureEditField
        function EndingTemperatureEditFieldValueChanged(app, event)
            value = app.EndingTemperatureEditField.Value;
            app.temperature = app.StartingTemperatureEditField.Value:app.TemperatureStepEditField.Value:app.EndingTemperatureEditField.Value;
        end

        % Value changed function: StartingTemperatureEditField
        function StartingTemperatureEditFieldValueChanged(app, event)
            value = app.StartingTemperatureEditField.Value;
            app.temperature = min(app.StartingTemperatureEditField.Value,app.EndingTemperatureEditField.Value):app.TemperatureStepEditField.Value:max(app.StartingTemperatureEditField.Value,app.EndingTemperatureEditField.Value);
        end

        % Value changed function: ThermocoupleTypeDropDown
        function ThermocoupleTypeDropDownValueChanged(app, event)
            value = app.ThermocoupleTypeDropDown.Value;
            app.thermocouple_type = app.ThermocoupleTypeDropDown.Value;
        end

        % Value changed function: ClearScreenChkBox
        function ClearScreenChkBoxValueChanged(app, event)
            value = app.ClearScreenChkBox.Value;
        end

        % Value changed function: BestThermChkBox
        function BestThermChkBoxValueChanged(app, event)
            value = app.BestThermChkBox.Value;
        end

        % Callback function
        function ClearaxesCheckBoxValueChanged(app, event)
            value = app.ClearaxesCheckBox.Value;
            if (value) cla(app.UIAxes,'reset'); end
        end

        % Button pushed function: ClearAxesButton
        function ClearAxesButtonPushed(app, event)
            cla(app.UIAxes,'reset');
            xlabel(app.UIAxes,'Temperature');ylabel(app.UIAxes,'Voltage');
        end

        % Callback function
        function TemperatureStepEditFieldValueChanged(app, event)
            value = app.TemperatureStepEditField.Value;
        end

        % Value changed function: TemperatureStepEditField
        function TemperatureStepEditFieldValueChanged2(app, event)
            value = app.TemperatureStepEditField.Value;
        end

        % Value changed function: PlotInFigureChkBox
        function PlotInFigureChkBoxValueChanged(app, event)
            value = app.PlotInFigureChkBox.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ThermocoupleSimulatorUIFigure = uifigure;
            app.ThermocoupleSimulatorUIFigure.Name = 'Thermocouple Simulator ';
            app.UIAxes = uiaxes(app.ThermocoupleSimulatorUIFigure);
            title(app.UIAxes, 'Themocouple voltage response')
            xlabel(app.UIAxes, 'Celcius')
            ylabel(app.UIAxes, 'Voltage')
            app.SimulateButton = uibutton(app.ThermocoupleSimulatorUIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.StartingTemperatureEditFieldLabel = uilabel(app.ThermocoupleSimulatorUIFigure);
            app.StartingTemperatureEditFieldLabel.Text = 'Starting Temperature';
            app.StartingTemperatureEditField = uieditfield(app.ThermocoupleSimulatorUIFigure, 'numeric');
            app.StartingTemperatureEditField.RoundFractionalValues = 'on';
            app.StartingTemperatureEditField.ValueChangedFcn = createCallbackFcn(app, @StartingTemperatureEditFieldValueChanged, true);
            app.ThermocoupleTypeDropDownLabel = uilabel(app.ThermocoupleSimulatorUIFigure);
            app.ThermocoupleTypeDropDownLabel.Text = 'Thermocouple Type';
            app.ThermocoupleTypeDropDown = uidropdown(app.ThermocoupleSimulatorUIFigure);
            app.ThermocoupleTypeDropDown.Items = {'Type J', 'Type K', 'Type E', 'Type T', 'Type N', 'Type B', 'Type R', 'Type S'};
            app.ThermocoupleTypeDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.ThermocoupleTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ThermocoupleTypeDropDownValueChanged, true);
            app.ThermocoupleTypeDropDown.Value = '1';
            app.EndingTemperatureEditFieldLabel = uilabel(app.ThermocoupleSimulatorUIFigure);
            app.EndingTemperatureEditFieldLabel.Text = 'Ending Temperature';
            app.EndingTemperatureEditField = uieditfield(app.ThermocoupleSimulatorUIFigure, 'numeric');
            app.EndingTemperatureEditField.RoundFractionalValues = 'on';
            app.EndingTemperatureEditField.ValueChangedFcn = createCallbackFcn(app, @EndingTemperatureEditFieldValueChanged, true);
            app.ClearScreenChkBox = uicheckbox(app.ThermocoupleSimulatorUIFigure);
            app.ClearScreenChkBox.ValueChangedFcn = createCallbackFcn(app, @ClearScreenChkBoxValueChanged, true);
            app.ClearScreenChkBox.Text = 'Don''t clear screen';
            app.BestThermChkBox = uicheckbox(app.ThermocoupleSimulatorUIFigure);
            app.BestThermChkBox.ValueChangedFcn = createCallbackFcn(app, @BestThermChkBoxValueChanged, true);
            app.BestThermChkBox.Text = 'Find best thermocouple';
            app.ClearAxesButton = uibutton(app.ThermocoupleSimulatorUIFigure, 'push');
            app.ClearAxesButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAxesButtonPushed, true);
            app.ClearAxesButton.Text = 'Clear Axes';
            app.TemperatureStepEditFieldLabel = uilabel(app.ThermocoupleSimulatorUIFigure);
            app.TemperatureStepEditFieldLabel.Text = 'Temperature Step';
            app.TemperatureStepEditField = uieditfield(app.ThermocoupleSimulatorUIFigure, 'numeric');
            app.TemperatureStepEditField.ValueChangedFcn = createCallbackFcn(app, @TemperatureStepEditFieldValueChanged2, true);
            app.TemperatureStepEditField.Value = 0.01;
            app.PlotInFigureChkBox = uicheckbox(app.ThermocoupleSimulatorUIFigure);
            app.PlotInFigureChkBox.ValueChangedFcn = createCallbackFcn(app, @PlotInFigureChkBoxValueChanged, true);
            app.PlotInFigureChkBox.Text = 'Plot in figure (extra capabilities)';
        end
    end

    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ThermocoupleSimulatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ThermocoupleSimulatorUIFigure)
        end
    end
end